classdef NoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        NoiseAxes                 matlab.ui.control.UIAxes
        StartEditFieldLabel       matlab.ui.control.Label
        StartEditField            matlab.ui.control.EditField
        EndEditFieldLabel         matlab.ui.control.Label
        EndEditField              matlab.ui.control.EditField
        MeasurementdurationLabel  matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        ImportButton              matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        ButtonGroup               matlab.ui.container.ButtonGroup
        ImportfileButton          matlab.ui.control.RadioButton
        ImportfolderButton        matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Patient;
        filesToAnalyze;
        CurrentPatientID;
        selectedFolder;
        currentPatientIndex;
        Company;
        afterHoursTable;
        keyTable;
    end
    
    methods (Access = private)
        
        function [StartTime, StopTime] = findWorkHours(app)
            dataTable = app.afterHoursTable(app.afterHoursTable.PatientID == str2num(app.Patient(app.currentPatientIndex).ID),:);
            % One of the files has 17 as time, and this will therefore fail. Must be fixed
            endOfWork = datetime(num2str(dataTable.Fyraften),'InputFormat','HH.mm',"Format","HH:mm:ss" );
            
            StopTime = endOfWork;
            StartTime = app.Patient(app.currentPatientIndex).FileData.Measurement(1,:).Time;
        end
        
        function findCompanyInformation(app)
            dataTable = app.keyTable(app.keyTable.PatientID == str2num(app.Patient(app.currentPatientIndex).ID), :);
            app.Patient(app.currentPatientIndex).Company.ID = dataTable.VirksomhedsID;
            app.Patient(app.currentPatientIndex).Company.Name = dataTable.VirksomhedsNavn;
        end
        
        function updateTextBoxes(app)
            app.StartEditField.Value = datestr(app.Patient(app.currentPatientIndex).FileData.Measurement(1,:).fullData);
            app.EndEditField.Value   = datestr(app.Patient(app.currentPatientIndex).FileData.Measurement(height(app.Patient(app.currentPatientIndex).FileData.Measurement),:).fullData);
        end
        
        function calcuateWorkSoundPressureLevel(app)
            app.Patient(app.currentPatientIndex).FileData.Measurement(1,:);
            measurementInterval = app.Patient(app.currentPatientIndex).FileData.Measurement(...
                app.Patient(app.currentPatientIndex).FileData.Measurement(:,:).fullData >= datetime(app.StartEditField.Value) & ...
                app.Patient(app.currentPatientIndex).FileData.Measurement(:,:).fullData <= datetime(app.EndEditField.Value),:);
            
            app.Patient(app.currentPatientIndex).Work.Measurement = measurementInterval;
            
            app.Patient(app.currentPatientIndex).Work.Leq = calculateLeq(app, app.Patient(app.currentPatientIndex).Work.Measurement);
        end
        
        function calculateTotalSoundPressureLevel(app)
            app.Patient(app.currentPatientIndex).FileData.Leq = calculateLeq(app, app.Patient(app.currentPatientIndex).FileData.Measurement);
            
            app.Patient(app.currentPatientIndex).FileData.Start = app.Patient(app.currentPatientIndex).FileData.Measurement(1,:).fullData;
            app.Patient(app.currentPatientIndex).FileData.Stop = app.Patient(app.currentPatientIndex).FileData.Measurement(height(app.Patient(app.currentPatientIndex).FileData.Measurement),:).fullData
            
            app.Patient(app.currentPatientIndex).FileData.Duration = app.Patient(app.currentPatientIndex).FileData.Stop - app.Patient(app.currentPatientIndex).FileData.Start;
            [h, m, s] = hms(app.Patient(app.currentPatientIndex).FileData.Duration);
            app.MeasurementdurationLabel.Text = sprintf('Measurement duration: %.0f:%.0f:%.0f', h, m, s);
        end
        
        function leq = calculateLeq(app, dosimetriData)
            nominator = 0;
            validReadings = 0;
            for i = 1:height(dosimetriData)
                value = dosimetriData.Leq(i);
                if (isnan(value) == false)
                    nominator = nominator + 10^(value/10);
                    validReadings = validReadings + 1;
                end
            end
            leq = log10(nominator/validReadings)*10;
        end
        
        function leq = calculateLeqBasedOnReadings(app, readings)
            nominator = 0;
            for i = 1:length(readings)
                nominator = nominator + 10^(readings(i)/10);
            end
            leq = log10(nominator/length(readings))*10;
        end
        
        function updateGraph(app)
            plot(app.Patient(app.currentPatientIndex).FileData.Measurement.fullData,app.Patient(app.currentPatientIndex).FileData.Measurement.Leq, 'Parent',app.NoiseAxes);
            yline(app.NoiseAxes,app.Patient(app.currentPatientIndex).FileData.Leq,'-','Leq_{max}');
        end
        
        function updateGraphBasedOnUserInput(app)
            plot(app.Patient(app.currentPatientIndex).Work.Measurement.fullData,app.Patient(app.currentPatientIndex).Work.Measurement.Leq, 'Parent',app.NoiseAxes);
            yline(app.NoiseAxes,app.Patient(app.currentPatientIndex).Work.Leq,'-','Leq_{work}');
            yline(app.NoiseAxes,app.Patient(app.currentPatientIndex).FileData.Leq,'-','Leq_{max}');
        end
        function exportDataAsCsv(app)
            DataToFile = {app.Patient(app.currentPatientIndex).ID, ...
                app.Patient(app.currentPatientIndex).Company.ID, ...
                app.Patient(app.currentPatientIndex).Company.Name, ...
                app.Patient(app.currentPatientIndex).FileData.Leq, ...
                app.Patient(app.currentPatientIndex).FileData.Start, ...
                app.Patient(app.currentPatientIndex).FileData.Stop, ...
                app.Patient(app.currentPatientIndex).FileData.Duration, ...
                app.Patient(app.currentPatientIndex).Work.Leq, ...
                app.StartEditField.Value, ...
                app.EndEditField.Value};
            DataToTable = cell2table(DataToFile);
            DataToTable.Properties.VariableNames = {'PatientId', 'CompanyId', 'CompanyName', 'TotalLeq', ...
                'MeasurementStartTime','MeasurementStopTime','MeasureDuration', ...
                'UserConfigureredLeq', 'UserConfiguredStartTime','UserConfiguredStopTime'};
            currentTime = datestr(now,'dd-MM-yyyy_HH-mm-ss')
            fileName = sprintf('Company%.0f_Patient%s_%s.csv', app.Patient(app.currentPatientIndex).Company.ID, app.Patient(app.currentPatientIndex).ID, currentTime)
            writetable(DataToTable,fileName, 'Delimiter',';','WriteVariableNames',true);
        end
    
        function automateFiles(app)
         f = waitbar(.00,'Loading files from folder');

         companyIds = zeros(length(app.filesToAnalyze),1);
            for i = 1:length(app.filesToAnalyze)
                app.currentPatientIndex = i;
                waitbar(i/length(app.filesToAnalyze),f,'Loading patient file...');

                fullfilePath = fullfile(app.selectedFolder, app.filesToAnalyze(i).name);
                [~, fileName, ~] = fileparts(fullfilePath);
                app.CurrentPatientID = fileName;
                app.Patient(i).ID = fileName;
                
                app.Patient(i).FileData.Measurement = importSingleFileFunction(fullfilePath);
                app.Patient(i).FileData.Leq = calculateLeq(app, app.Patient(i).FileData.Measurement);
                
                [StartTime, StopTime] = findWorkHours(app);
                app.Patient(i).Work.Start = StartTime;
                app.Patient(i).Work.Stop = StopTime;
        
                app.Patient(i).Work.Measurement = app.Patient(i).FileData.Measurement(...
                    app.Patient(i).FileData.Measurement(:,:).Time >= StartTime & ...
                    app.Patient(i).FileData.Measurement(:,:).Time <= StopTime,:);
                    
                app.Patient(i).Work.Leq = calculateLeq(app, app.Patient(i).Work.Measurement);
                
                findCompanyInformation(app);
               
                app.Patient(i).Sparetime.Measurement = app.Patient(i).FileData.Measurement(app.Patient(i).FileData.Measurement(:,:).Datapoint > height(app.Patient(i).Work.Measurement),:);
                companyIds(i) = app.Patient(i).Company.ID;
            end
            uniqueCompanyIds = unique(companyIds);
            
            for i = 1:length(uniqueCompanyIds)
                text = sprintf('Performing calculations for firm %.0f',uniqueCompanyIds(i)) 
                waitbar(i/length(uniqueCompanyIds),f,text');
                app.Company(i).ID = uniqueCompanyIds(i);
                count = 1;
                for q = 1:numel(app.Patient)
                    if(app.Patient(q).Company.ID == app.Company(i).ID)
                        app.Company(i).Readings(count) = app.Patient(q).Work.Leq;
                        count = count + 1;
                    end
                end
                app.Company(i).CombinedLeq = calculateLeqBasedOnReadings(app,app.Company(i).Readings);
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if(app.ImportfileButton.Value == true)
                [FileName, Pathname] = uigetfile({'*.txt'}, 'Select .txt file');
                if FileName ~=0
                    app.currentPatientIndex = 1;
                    Fullpath = fullfile(Pathname, FileName);
                    f = waitbar(.25,'Loading patient file...');
                    app.Patient(app.currentPatientIndex).FileData.Measurement = importSingleFileFunction(Fullpath);
                    
                    IdCellArray = strsplit(FileName, '.'); %%Splitting Filename into two parts; Filename and file extension
                    app.Patient(app.currentPatientIndex).ID = IdCellArray{1,:};
                    waitbar(.40,f,'Loading key file...');
                    app.keyTable = readtable('Data/Noeglefil.csv');
                    waitbar(.60,f,'Loading work hours...');
                    app.afterHoursTable = readtable('Data\Fyraften.csv');
                    waitbar(.80,f,'Performing calculations...');
                    findCompanyInformation(app);
                    findWorkHours(app);
                    updateTextBoxes(app);
                    calculateTotalSoundPressureLevel(app);
                    waitbar(1,f,'Updating figure...');
                    updateGraph(app);
                    close(f)
                end
            end
            if(app.ImportfolderButton.Value == true)
                app.selectedFolder = uigetdir;
                app.keyTable = readtable('Data/Noeglefil.csv');
                app.afterHoursTable = readtable('Data\Fyraften.csv');
                app.filesToAnalyze = dir(fullfile(app.selectedFolder,'*.txt'));
                % Emptying patient struct to clear patient struct in case a single file was imported.
                app.Patient = [];
                automateFiles(app);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calcuateWorkSoundPressureLevel(app);
            updateGraphBasedOnUserInput(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportDataAsCsv(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.NoiseAxes = uiaxes(app.Panel);
            title(app.NoiseAxes, 'Title')
            xlabel(app.NoiseAxes, 'Time(s)')
            ylabel(app.NoiseAxes, 'Sound Pressure Level[dB]')
            app.StartEditFieldLabel = uilabel(app.Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel, 'text');
            app.EndEditFieldLabel = uilabel(app.Panel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Panel, 'text');
            app.MeasurementdurationLabel = uilabel(app.Panel);
            app.MeasurementdurationLabel.Text = 'Measurement duration:';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ImportButton = uibutton(app.Panel_2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ImportfileButton = uiradiobutton(app.ButtonGroup);
            app.ImportfileButton.Text = 'Import file';
            app.ImportfileButton.Value = true;
            app.ImportfolderButton = uiradiobutton(app.ButtonGroup);
            app.ImportfolderButton.Text = 'Import folder';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NoiseApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end